classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ConnectButton        matlab.ui.control.Button
        CloseButton          matlab.ui.control.Button
        valueEditFieldLabel  matlab.ui.control.Label
        valueEditField       matlab.ui.control.NumericEditField
        UIAxes               matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            close all;
            clc;
            delete(instrfind({'Port'},{'COM7'}));
            uno = serial('COM7');
            uno.Baudrate = 115200;
            fopen(uno);
            global  state 
            count =1;
            state = 0;
            sample = 300000;
            while (count <= sample)
                if state == 1
                    break;
                else
                    displacement = fscanf(uno,'%d');
                    displacement = num2str(displacement);
                    app.valueEditField.Value = displacement;
                    plot(app.UIAxes,displacement);
                    drawnow;
                    count = count +1;
                    
                     
                end
                
            end
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            global state 
            state = 1;
            delete(instrfind({'Port'},{'COM7'}));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.valueEditFieldLabel = uilabel(app.UIFigure);
            app.valueEditFieldLabel.Text = 'value';
            app.valueEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Char')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'displacement (mm)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end